classdef activity2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        xtcos2Acos2BPanel  matlab.ui.container.Panel
        UIAxes_8           matlab.ui.control.UIAxes
        UIAxes_7           matlab.ui.control.UIAxes
        xtsin2Asin2BPanel  matlab.ui.container.Panel
        UIAxes_6           matlab.ui.control.UIAxes
        UIAxes_5           matlab.ui.control.UIAxes
        PlotButton         matlab.ui.control.Button
        BSlider            matlab.ui.control.Slider
        BSliderLabel       matlab.ui.control.Label
        ASlider            matlab.ui.control.Slider
        ASliderLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
%Keith Harris
%EE314L
%Lab4
%9.30.2022
clc;
N = 250;		% Sets the sequence length to 250
Ts = 0.0002;		% The sampling rate
t = [0:N-1]*Ts;	% The sampling range
func_sin = sin(2*pi*app.ASlider.Value*t) + sin(2*pi* app.BSlider.Value*t); %setting up sin function note the reference to the slider value
func_cos = cos(2*pi*app.ASlider.Value*t) + cos(2*pi* app.BSlider.Value*t); %setting up cos function 
k = 0;

for f = 0:1:800
	k = k+1;
	X(k) = trapz(t, func_sin.*exp(-j*2*pi*f*t));
    Y(k) = trapz(t, func_cos.*exp(-j*2*pi*f*t)); 
end

f = 0:1:800;
plot(app.UIAxes_5,f,abs(X), 'r') % plot in app designer almost identical to matlab proper
plot(app.UIAxes_6,t,func_sin, 'r')% a ref to the plot is needed "app.UIAxes_6"
plot(app.UIAxes_7,f,abs(Y), 'b')
plot(app.UIAxes_8,t,func_cos, 'b')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ASliderLabel = uilabel(app.UIFigure);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.UIFigure);
            app.ASlider.Limits = [0 1000];
            app.BSliderLabel = uilabel(app.UIFigure);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.UIFigure);
            app.BSlider.Limits = [0 1000];
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot!';
            app.xtsin2Asin2BPanel = uipanel(app.UIFigure);
            app.xtsin2Asin2BPanel.Title = 'x(t)=sin(2Aπ)+sin(2Bπ) ';
            app.UIAxes_5 = uiaxes(app.xtsin2Asin2BPanel);
            title(app.UIAxes_5, 'Magnitute Spectrum')
            xlabel(app.UIAxes_5, 'Time')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.xtsin2Asin2BPanel);
            title(app.UIAxes_6, 'Phase Spectrum')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.xtcos2Acos2BPanel = uipanel(app.UIFigure);
            app.xtcos2Acos2BPanel.Title = 'x(t)=cos⁡(2Aπ)+cos⁡(2Bπ) ';
            app.UIAxes_7 = uiaxes(app.xtcos2Acos2BPanel);
            title(app.UIAxes_7, 'Magnitute Spectrum')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.xtcos2Acos2BPanel);
            title(app.UIAxes_8, 'Phase Spectrum')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = activity2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end