classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PlotButton               matlab.ui.control.Button
        xtsin2xsin2ySwitch       matlab.ui.control.Switch
        xtsin2xsin2ySwitchLabel  matlab.ui.control.Label
        xtcos2xcos2ySwitch       matlab.ui.control.Switch
        Label                    matlab.ui.control.Label
        YValueSlider             matlab.ui.control.Slider
        YValueSliderLabel        matlab.ui.control.Label
        XValueSlider             matlab.ui.control.Slider
        XValueSliderLabel        matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: XValueSlider
        function XValueSliderValueChanged(app, event)
            Xvalue = app.XValueSlider.Value;
            
        end

        % Value changed function: YValueSlider
        function YValueSliderValueChanged(app, event)
            Yvalue = app.YValueSlider.Value;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
cla(app.UIAxes)   
clc;
N = 250;		% Sets the sequence length to 250
Ts = 0.0002;		% The sampling rate
t = [0:N-1]*Ts;	% The sampling range
func_sin = sin(2*pi*app.XValueSlider.Value*t) + sin(2*pi* app.YValueSlider.Value*t);
func_cos = cos(2*pi*app.XValueSlider.Value*t) + cos(2*pi* app.YValueSlider.Value*t);
%figure
%plot(t,x)
k = 0;
for f = 0:1:800
	k = k+1;
	X(k) = trapz(t, func_sin.*exp(-j*2*pi*f*t));
    Y(k) = trapz(t, func_cos.*exp(-j*2*pi*f*t)); 
end

f = 0:1:800;
hold (app.UIAxes, 'on')
if app.xtsin2xsin2ySwitch.Value == 'On'
    plot(app.UIAxes,f,abs(X), 'b')
end
if app.xtsin2xsin2ySwitch.Value == 'Off'
    plot(app.UIAxes,f,abs(X), 'g')
end
if app.xtcos2xcos2ySwitch.Value == 'On'
    plot(app.UIAxes,f,abs(Y), 'r')
end

hold (app.UIAxes, 'off')
%plot(f, abs(X))


        end

        % Value changed function: xtcos2xcos2ySwitch
        function xtcos2xcos2ySwitchValueChanged(app, event)
            value = app.xtcos2xcos2ySwitch.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.XValueSliderLabel = uilabel(app.UIFigure);
            app.XValueSliderLabel.Text = 'X Value';
            app.XValueSlider = uislider(app.UIFigure);
            app.XValueSlider.Limits = [0 1000];
            app.XValueSlider.ValueChangedFcn = createCallbackFcn(app, @XValueSliderValueChanged, true);
            app.YValueSliderLabel = uilabel(app.UIFigure);
            app.YValueSliderLabel.Text = 'Y Value';
            app.YValueSlider = uislider(app.UIFigure);
            app.YValueSlider.Limits = [0 1000];
            app.YValueSlider.ValueChangedFcn = createCallbackFcn(app, @YValueSliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'x(t)=cos⁡(2xπ)+cos⁡(2yπ) ';
            app.xtcos2xcos2ySwitch = uiswitch(app.UIFigure, 'slider');
            app.xtcos2xcos2ySwitch.ValueChangedFcn = createCallbackFcn(app, @xtcos2xcos2ySwitchValueChanged, true);
            app.xtcos2xcos2ySwitch.Value = 'On';
            app.xtsin2xsin2ySwitchLabel = uilabel(app.UIFigure);
            app.xtsin2xsin2ySwitchLabel.Text = 'x(t)=sin⁡(2xπ)+sin⁡(2yπ) ';
            app.xtsin2xsin2ySwitch = uiswitch(app.UIFigure, 'slider');
            app.xtsin2xsin2ySwitch.Value = 'On';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end